classdef PoseEstimation3DApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        TabGroup            matlab.ui.container.TabGroup
        Tab0                matlab.ui.container.Tab
        Slider0_Video2      matlab.ui.control.Slider
        Slider0_Video1      matlab.ui.control.Slider
        EditField0_Video2   matlab.ui.control.EditField
        Label_2             matlab.ui.control.Label
        Button0_LoadVideo2  matlab.ui.control.Button
        Button0_LoadVideo1  matlab.ui.control.Button
        EditField0_Video1   matlab.ui.control.EditField
        Label               matlab.ui.control.Label
        UIAxes0_2           matlab.ui.control.UIAxes
        UIAxes0_1           matlab.ui.control.UIAxes
        Tab1                matlab.ui.container.Tab
        Slider1_2           matlab.ui.control.Slider
        Slider1_1           matlab.ui.control.Slider
        Lamp1               matlab.ui.control.Lamp
        DonegreenLampLabel  matlab.ui.control.Label
        ButtonGroup1        matlab.ui.container.ButtonGroup
        Button1_load        matlab.ui.control.RadioButton
        Button1_DL          matlab.ui.control.RadioButton
        Button1_execute     matlab.ui.control.Button
        UIAxes1_2           matlab.ui.control.UIAxes
        UIAxes1_1           matlab.ui.control.UIAxes
        Tab_2               matlab.ui.container.Tab
        ButtonGroup2        matlab.ui.container.ButtonGroup
        Button2_2           matlab.ui.control.RadioButton
        Button2_1           matlab.ui.control.RadioButton
        Button2             matlab.ui.control.StateButton
        Slider2             matlab.ui.control.Slider
        Label_3             matlab.ui.control.Label
        UIAxes2             matlab.ui.control.UIAxes
        Tab_3               matlab.ui.container.Tab
        Tab_4               matlab.ui.container.Tab
        Tab_5               matlab.ui.container.Tab
        UIAxes_2            matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        video_filename1 % Description
        video_filename2 % Description
        video_property1
        frameNum1
        video_property2
        frameNum2
        
        doDetection = true;
        
        videowrite_filename1 = 'Results/Video/video1_cor.mp4';
        videowrite_filename2 = 'Results/Video/video2_cor.mp4';
        
        newOrigin1
        newOrigin2
        
        cameraParams1
        cameraParams2

        object_detector
        pose_detector
        
        keypointsArray1
        keypointsArray2
        sm_keypointsArray1
        sm_keypointsArray2
        
        imSize1
        imSize2
        
        pauseS
    end
    
    methods (Access = private)
        
        function results = continuousShowKeyFrames(app)
            CorrectionDegree = app.Slider2.Value;
            selectedButton = app.ButtonGroup2.SelectedObject;
            if strcmp(selectedButton.Text,'ビデオ1')
                frameNum = app.frameNum1;
                keypointsArray = app.keypointsArray1;
            else
                frameNum = app.frameNum2;
                keypointsArray = app.keypointsArray2;
            end
            correctionWidth = round(CorrectionDegree/100*(frameNum-1));
            
            sm_keypointsArray = smoothdata(keypointsArray,3,'rloess',correctionWidth);
            
            if strcmp(selectedButton.Text,'ビデオ1')
                app.sm_keypointsArray1 = sm_keypointsArray;
                app.sm_keypointsArray2 = smoothdata(app.keypointsArray2,3,'rloess',correctionWidth);
            else
                app.sm_keypointsArray1 = smoothdata(app.keypointsArray1,3,'rloess',correctionWidth);
                app.sm_keypointsArray2 = sm_keypointsArray;
            end
            
            if strcmp(selectedButton.Text,'ビデオ1')
                backIm = ones(app.imSize1(1),app.imSize1(2),3);
            else
                backIm = ones(app.imSize2(1),app.imSize2(2),3);
            end
            
            iter = 1;
            app.pauseS = false;
            while true
                K = app.pose_detector.visualizeKeyPoints(backIm,sm_keypointsArray(:,:,iter));    
                imshow(K,'Parent',app.UIAxes2);
                drawnow;
                pause(0.001);
                iter = iter+1;
                if app.pauseS
                    break;
                end
                if iter > frameNum
                    app.Button2.Value = false;
                    app.Button2.Text = '再生';
                    break;
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIAxes0_1.XLabel.String = '';
            app.UIAxes0_1.YLabel.String = '';
            app.UIAxes0_2.XLabel.String = '';
            app.UIAxes0_2.YLabel.String = '';            
            app.UIAxes1_1.XLabel.String = '';
            app.UIAxes1_1.YLabel.String = '';
            app.UIAxes1_2.XLabel.String = '';
            app.UIAxes1_2.YLabel.String = '';
            

            app.Lamp1.Color = 'r';
            
            load('Data\cameraParams\cameraparams.mat');
            app.cameraParams1 = cameraParams1;
            app.cameraParams2 = cameraParams2;
            
            object_detector = [];
%            app.object_detector = loadObjectDetector(object_detector);
            pose_detector = [];
            app.pose_detector = loadPoseDetector(pose_detector);
            
            app.Slider2.Limits = [1 100];

        end

        % Button pushed function: Button0_LoadVideo1
        function Button0_LoadVideo1Pushed(app, event)
            [video_filename1,path] = uigetfile('Data/Video/*.mp4');
            app.video_filename1 = fullfile(path,video_filename1);
            app.EditField0_Video1.Value = app.video_filename1;
            app.video_property1 = VideoReader(app.video_filename1);
            app.frameNum1 = app.video_property1.NumFrames;
            app.Slider0_Video1.Limits = [1 app.frameNum1];
            frame1 = read(app.video_property1,1);
            imshow(frame1,'Parent',app.UIAxes0_1);
        end

        % Button pushed function: Button0_LoadVideo2
        function Button0_LoadVideo2Pushed(app, event)
            [video_filename1,path] = uigetfile('Data/Video/*.mp4');
            app.video_filename2 = fullfile(path,video_filename1);
            app.EditField0_Video2.Value = app.video_filename2;            
            app.video_property2 = VideoReader(app.video_filename2);
            app.frameNum2 = app.video_property2.NumFrames;
            app.Slider0_Video2.Limits = [1 app.frameNum2];
            frame2 = read(app.video_property2,1);
            imshow(frame2,'Parent',app.UIAxes0_2);
        end

        % Value changed function: Slider0_Video2
        function Slider0_Video2ValueChanged(app, event)
            value = app.Slider0_Video2.Value;
            frame = read(app.video_property2,round(value));
            imshow(frame,'Parent',app.UIAxes0_2);
        end

        % Value changed function: Slider0_Video1
        function Slider0_Video1ValueChanged(app, event)
            value = app.Slider0_Video1.Value;
            frame = read(app.video_property1,round(value));
            imshow(frame,'Parent',app.UIAxes0_1);
        end

        % Selection changed function: ButtonGroup1
        function ButtonGroup1SelectionChanged(app, event)
            selectedButton = app.ButtonGroup1.SelectedObject;
            if strcmp(selectedButton.Text,'計算')
                app.doDetection = true;
            elseif strcmp(selectedButton.Text,'読み込み')
                app.doDetection = false;
            end
        end

        % Button pushed function: Button1_execute
        function Button1_executePushed(app, event)
            
            if app.doDetection
                keypointsArray1 = zeros(17,3,app.frameNum1);
                keypointsArray2 = zeros(17,3,app.frameNum2);
    
                % 読み込み用
                vr1 = vision.VideoFileReader(app.video_filename1);
                vr2 = vision.VideoFileReader(app.video_filename2);
                % 書き込み用
                vw1 = vision.VideoFileWriter(app.videowrite_filename1,'FileFormat','MPEG4','FrameRate',app.video_property1.FrameRate);
                vw2 = vision.VideoFileWriter(app.videowrite_filename2,'FileFormat','MPEG4','FrameRate',app.video_property2.FrameRate);
                i = 1;
                f = waitbar(i/app.frameNum1,"Calculating...");
                while ~isDone(vr1)
                       frame = vr1();
                       [cor_frame,newOrigin1] = undistortImage(frame,app.cameraParams1);
                       [keypoints,isdetected] = poseDetection4Frame(cor_frame,object_detector,pose_detector);
                       keypointsArray1(:,:,i) = keypoints;
                       i = i+1;
                       waitbar(i/frameNum1,f);
                       vw1(cor_frame);
                end
                close(f);
                release(vw1);
                
                i = 1;
                f = waitbar(i/app.frameNum2,"Calculating...");
                while ~isDone(vr2)
                       frame = vr2();
                       frame = preprocess_for_video2Frames(frame); % 明るくする前処理
                       [cor_frame,newOrigin2] = undistortImage(frame,app.cameraParams2);
                       [keypoints,isdetected] = poseDetection4Frame(cor_frame,object_detector,pose_detector);
                       keypointsArray2(:,:,i) = keypoints;
                       i = i+1;
                       waitbar(i/app.frameNum2,f);
                       vw2(cor_frame);
                end
                close(f);
                release(vw2);
                
                save Results/keypoints/detectionResults.mat keypointsArray1 keypointsArray2
                save Results/keypoints/newOrigins.mat newOrigin1 newOrigin2
            end
            load('Results/keypoints/detectionResults.mat');
            
            app.keypointsArray1 = keypointsArray1;
            app.keypointsArray2 = keypointsArray2;
            
            app.Lamp1.Color = 'g';

            frame1 = read(app.video_property1,1);
            frame1 = app.pose_detector.visualizeKeyPoints(frame1,app.keypointsArray1(:,:,1));
            imshow(frame1,'Parent',app.UIAxes1_1);

            frame2 = read(app.video_property2,2);
            frame2 = app.pose_detector.visualizeKeyPoints(frame2,app.keypointsArray2(:,:,1));
            imshow(frame2,'Parent',app.UIAxes1_2);
            
            app.video_property1 = VideoReader(app.videowrite_filename1);
            app.video_property2 = VideoReader(app.videowrite_filename2);
            app.imSize1 = [app.video_property1.Height app.video_property1.Width];
            app.imSize2 = [app.video_property2.Height app.video_property2.Width];
        end

        % Value changed function: Slider1_1
        function Slider1_1ValueChanged(app, event)
            value = app.Slider1_1.Value;
            
            frame1 = read(app.video_property1,round(value));
            frame1 = app.pose_detector.visualizeKeyPoints(frame1,app.keypointsArray1(:,:,round(value)));
            imshow(frame1,'Parent',app.UIAxes1_1);            
        end

        % Value changed function: Slider1_2
        function Slider1_2ValueChanged(app, event)
            value = app.Slider1_2.Value;

            frame1 = read(app.video_property2,round(value));
            frame1 = app.pose_detector.visualizeKeyPoints(frame1,app.keypointsArray2(:,:,round(value)));
            imshow(frame1,'Parent',app.UIAxes1_2);                        
        end

        % Value changed function: Button2
        function Button2ValueChanged(app, event)
            value = app.Button2.Value;
            
            if value
                app.Button2.Text = '停止';
                app.pauseS = false;
                continuousShowKeyFrames(app);
            else
                app.Button2.Text = '再生';
                app.pauseS = true;
            end    
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab0 = uitab(app.TabGroup);
            app.Tab0.Title = '０ビデオの読み込み';
            app.UIAxes0_1 = uiaxes(app.Tab0);
            title(app.UIAxes0_1, 'ビデオ１')
            xlabel(app.UIAxes0_1, 'X')
            ylabel(app.UIAxes0_1, 'Y')
            zlabel(app.UIAxes0_1, 'Z')
            app.UIAxes0_2 = uiaxes(app.Tab0);
            title(app.UIAxes0_2, 'ビデオ２')
            xlabel(app.UIAxes0_2, 'X')
            ylabel(app.UIAxes0_2, 'Y')
            zlabel(app.UIAxes0_2, 'Z')
            app.Label = uilabel(app.Tab0);
            app.Label.Text = 'ビデオ1';
            app.EditField0_Video1 = uieditfield(app.Tab0, 'text');
            app.Button0_LoadVideo1 = uibutton(app.Tab0, 'push');
            app.Button0_LoadVideo1.ButtonPushedFcn = createCallbackFcn(app, @Button0_LoadVideo1Pushed, true);
            app.Button0_LoadVideo1.Text = 'ファイル指定';
            app.Button0_LoadVideo2 = uibutton(app.Tab0, 'push');
            app.Button0_LoadVideo2.ButtonPushedFcn = createCallbackFcn(app, @Button0_LoadVideo2Pushed, true);
            app.Button0_LoadVideo2.Text = 'ファイル指定';
            app.Label_2 = uilabel(app.Tab0);
            app.Label_2.Text = 'ビデオ2';
            app.EditField0_Video2 = uieditfield(app.Tab0, 'text');
            app.Slider0_Video1 = uislider(app.Tab0);
            app.Slider0_Video1.ValueChangedFcn = createCallbackFcn(app, @Slider0_Video1ValueChanged, true);
            app.Slider0_Video2 = uislider(app.Tab0);
            app.Slider0_Video2.ValueChangedFcn = createCallbackFcn(app, @Slider0_Video2ValueChanged, true);
            app.Tab1 = uitab(app.TabGroup);
            app.Tab1.Title = '１骨格検出';
            app.UIAxes1_1 = uiaxes(app.Tab1);
            title(app.UIAxes1_1, 'ビデオ1')
            xlabel(app.UIAxes1_1, 'X')
            ylabel(app.UIAxes1_1, 'Y')
            zlabel(app.UIAxes1_1, 'Z')
            app.UIAxes1_2 = uiaxes(app.Tab1);
            title(app.UIAxes1_2, 'ビデオ2')
            xlabel(app.UIAxes1_2, 'X')
            ylabel(app.UIAxes1_2, 'Y')
            zlabel(app.UIAxes1_2, 'Z')
            app.Button1_execute = uibutton(app.Tab1, 'push');
            app.Button1_execute.ButtonPushedFcn = createCallbackFcn(app, @Button1_executePushed, true);
            app.Button1_execute.Text = '実行';
            app.ButtonGroup1 = uibuttongroup(app.Tab1);
            app.ButtonGroup1.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup1SelectionChanged, true);
            app.ButtonGroup1.Title = '骨格検出';
            app.Button1_DL = uiradiobutton(app.ButtonGroup1);
            app.Button1_DL.Text = '計算';
            app.Button1_DL.Value = true;
            app.Button1_load = uiradiobutton(app.ButtonGroup1);
            app.Button1_load.Text = '読み込み';
            app.DonegreenLampLabel = uilabel(app.Tab1);
            app.DonegreenLampLabel.Text = 'Done (green)';
            app.Lamp1 = uilamp(app.Tab1);
            app.Slider1_1 = uislider(app.Tab1);
            app.Slider1_1.ValueChangedFcn = createCallbackFcn(app, @Slider1_1ValueChanged, true);
            app.Slider1_2 = uislider(app.Tab1);
            app.Slider1_2.ValueChangedFcn = createCallbackFcn(app, @Slider1_2ValueChanged, true);
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '２各関節位置の補正';
            app.UIAxes2 = uiaxes(app.Tab_2);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.Label_3 = uilabel(app.Tab_2);
            app.Label_3.Text = '補正強度';
            app.Slider2 = uislider(app.Tab_2);
            app.Button2 = uibutton(app.Tab_2, 'state');
            app.Button2.ValueChangedFcn = createCallbackFcn(app, @Button2ValueChanged, true);
            app.Button2.Text = '再生';
            app.ButtonGroup2 = uibuttongroup(app.Tab_2);
            app.ButtonGroup2.Title = '確認用';
            app.Button2_1 = uiradiobutton(app.ButtonGroup2);
            app.Button2_1.Text = 'ビデオ1';
            app.Button2_1.Value = true;
            app.Button2_2 = uiradiobutton(app.ButtonGroup2);
            app.Button2_2.Text = 'ビデオ2';
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '３ビデオの同期';
            app.Tab_4 = uitab(app.TabGroup);
            app.Tab_4.Title = '４カメラの位置計測';
            app.Tab_5 = uitab(app.TabGroup);
            app.Tab_5.Title = '５姿勢の3次元計測';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PoseEstimation3DApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end